classdef PIRT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PIRTUIFigure                    matlab.ui.Figure
        mainGrid                        matlab.ui.container.GridLayout
        HTMResultsTable                 matlab.ui.control.Table
        imgShowHide                     matlab.ui.control.Image
        lateralPanel                    matlab.ui.container.Panel
        lateralGrid                     matlab.ui.container.GridLayout
        Tab5_Grid                       matlab.ui.container.GridLayout
        Tab5_Image                      matlab.ui.control.Image
        INFOLabel                       matlab.ui.control.Label
        Tab4_Grid                       matlab.ui.container.GridLayout
        Tab4_Image                      matlab.ui.control.Image
        HEATTRANSFERMODULELabel         matlab.ui.control.Label
        Tab3_Grid                       matlab.ui.container.GridLayout
        Tab3_Image                      matlab.ui.control.Image
        RESULTSLabel                    matlab.ui.control.Label
        Tab2_Grid                       matlab.ui.container.GridLayout
        Tab2_Image                      matlab.ui.control.Image
        FILTERSELECTIONLabel_2          matlab.ui.control.Label
        Tab5Panel                       matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        TextArea                        matlab.ui.control.TextArea
        Tab4Panel                       matlab.ui.container.Panel
        Tab4Grid                        matlab.ui.container.GridLayout
        TabHTM                          matlab.ui.container.TabGroup
        Tab1                            matlab.ui.container.Tab
        GridTab1Htm                     matlab.ui.container.GridLayout
        HTMTable                        matlab.ui.control.Table
        ErrorestimationTab              matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        TextArea_3                      matlab.ui.control.TextArea
        HTMErrorTable                   matlab.ui.control.Table
        CustomqtermsTab                 matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        TextArea_4                      matlab.ui.control.TextArea
        NewRowQBtn                      matlab.ui.control.Button
        DeleteRowQBtn                   matlab.ui.control.Button
        CustomQTable                    matlab.ui.control.Table
        EnableqtermsLabel               matlab.ui.control.Label
        CustomQCheck                    matlab.ui.control.CheckBox
        OutputTab                       matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        FilteredHotHTMCheck             matlab.ui.control.CheckBox
        UsehotfilteredimagesLabel       matlab.ui.control.Label
        CroppingHTMInfo                 matlab.ui.control.EditField
        FilescroppedLabel_2             matlab.ui.control.Label
        TableHTMBtn                     matlab.ui.control.Button
        SaveHTMBtn                      matlab.ui.control.Button
        HTMSelectionDrop                matlab.ui.control.DropDown
        SelecttheresultstodisplayLabel_2  matlab.ui.control.Label
        DisplayHTMBtn                   matlab.ui.control.Button
        PlottingoptionsLabel_3          matlab.ui.control.Label
        HotInfo2                        matlab.ui.control.EditField
        ColdInfo2                       matlab.ui.control.EditField
        HotimagesloadedLabel_2          matlab.ui.control.Label
        ColdimagesloadedLabel_2         matlab.ui.control.Label
        SelectHTMoutputsLabel           matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        StCheck                         matlab.ui.control.CheckBox
        hCheck                          matlab.ui.control.CheckBox
        NuCheck                         matlab.ui.control.CheckBox
        StLabel                         matlab.ui.control.Label
        hLabel                          matlab.ui.control.Label
        NuLabel                         matlab.ui.control.Label
        ErrorCheckBox                   matlab.ui.control.CheckBox
        IncludeerrorestimationLabel     matlab.ui.control.Label
        HTMmessage                      matlab.ui.control.EditField
        RunHTMBtn                       matlab.ui.control.Button
        Tab4InputLabel                  matlab.ui.control.Label
        Tab3Panel                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        HotInfo                         matlab.ui.control.EditField
        ResultsTextArea                 matlab.ui.control.TextArea
        GridLayout4                     matlab.ui.container.GridLayout
        SelecttheresultstodisplayLabel  matlab.ui.control.Label
        DisplayBtn                      matlab.ui.control.Button
        PlottingoptionsLabel            matlab.ui.control.Label
        Display1Selection               matlab.ui.control.DropDown
        SaveresultsButton               matlab.ui.control.Button
        RunPIRTButton                   matlab.ui.control.Button
        FiltersInfo                     matlab.ui.control.EditField
        CropInfo                        matlab.ui.control.EditField
        FiltersselectedinorderLabel     matlab.ui.control.Label
        FilescroppedLabel               matlab.ui.control.Label
        HotimagesloadedLabel_3          matlab.ui.control.Label
        Tab2Panel                       matlab.ui.container.Panel
        FilterGrid                      matlab.ui.container.GridLayout
        TextArea_5                      matlab.ui.control.TextArea
        FilterSelectionGrid             matlab.ui.container.GridLayout
        Filter4ConfBtn                  matlab.ui.control.Button
        Filter3ConfBtn                  matlab.ui.control.Button
        Filter2ConfBtn                  matlab.ui.control.Button
        Filter1ConfBtn                  matlab.ui.control.Button
        GridFilter4                     matlab.ui.container.GridLayout
        TextArea_POD4                   matlab.ui.control.TextArea
        Wiener4Grid                     matlab.ui.container.GridLayout
        wiener4Table                    matlab.ui.control.Table
        Sgolay4Grid                     matlab.ui.container.GridLayout
        sgolay4Table                    matlab.ui.control.Table
        mPOD4Table                      matlab.ui.control.Table
        POD4Table                       matlab.ui.control.Table
        GridFilter3                     matlab.ui.container.GridLayout
        TextArea_POD3                   matlab.ui.control.TextArea
        Wiener3Grid                     matlab.ui.container.GridLayout
        wiener3Table                    matlab.ui.control.Table
        Sgolay3Grid                     matlab.ui.container.GridLayout
        sgolay3Table                    matlab.ui.control.Table
        mPOD3Table                      matlab.ui.control.Table
        POD3Table                       matlab.ui.control.Table
        GridFilter2                     matlab.ui.container.GridLayout
        TextArea_POD2                   matlab.ui.control.TextArea
        Wiener2Grid                     matlab.ui.container.GridLayout
        wiener2Table                    matlab.ui.control.Table
        Sgolay2Grid                     matlab.ui.container.GridLayout
        sgolay2Table                    matlab.ui.control.Table
        mPOD2Table                      matlab.ui.control.Table
        POD2Table                       matlab.ui.control.Table
        GridFilter1                     matlab.ui.container.GridLayout
        TextArea_POD1                   matlab.ui.control.TextArea
        Wiener1Grid                     matlab.ui.container.GridLayout
        wiener1Table                    matlab.ui.control.Table
        Sgolay1Grid                     matlab.ui.container.GridLayout
        sgolay1Table                    matlab.ui.control.Table
        mPOD1Table                      matlab.ui.control.Table
        POD1Table                       matlab.ui.control.Table
        Filter4DropDown                 matlab.ui.control.DropDown
        Filter3DropDown                 matlab.ui.control.DropDown
        Filter2DropDown                 matlab.ui.control.DropDown
        Filter1DropDown                 matlab.ui.control.DropDown
        Filter4Label                    matlab.ui.control.Label
        Filter2Label                    matlab.ui.control.Label
        Filter3Label                    matlab.ui.control.Label
        Filter1Label                    matlab.ui.control.Label
        Tab1Panel                       matlab.ui.container.Panel
        Tab1Grid                        matlab.ui.container.GridLayout
        CropCheck                       matlab.ui.control.CheckBox
        GridLayout11                    matlab.ui.container.GridLayout
        Y2Label                         matlab.ui.control.Label
        Y1Label                         matlab.ui.control.Label
        X2Label                         matlab.ui.control.Label
        X1Label                         matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        CropY2                          matlab.ui.control.NumericEditField
        CropY1                          matlab.ui.control.NumericEditField
        CropX2                          matlab.ui.control.NumericEditField
        CropX1                          matlab.ui.control.NumericEditField
        CroppedcoordinatesLabel         matlab.ui.control.Label
        CropBtn                         matlab.ui.control.Button
        InputLabel                      matlab.ui.control.Label
        FileLoadedHot                   matlab.ui.control.EditField
        FileLoadedCold                  matlab.ui.control.EditField
        LoadColdBtn                     matlab.ui.control.Button
        LoadHotBtn                      matlab.ui.control.Button
        Tab1_Grid                       matlab.ui.container.GridLayout
        Tab1_Image                      matlab.ui.control.Image
        INPUTIMAGESLabel                matlab.ui.control.Label
        mainPanel                       matlab.ui.container.Panel
        mainsubGrid                     matlab.ui.container.GridLayout
        PlotMainGrid                    matlab.ui.container.GridLayout
        imgShowHide2                    matlab.ui.control.Image
        GridLayout5                     matlab.ui.container.GridLayout
        RMSCheck                        matlab.ui.control.CheckBox
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        CLim2                           matlab.ui.control.NumericEditField
        CLim1                           matlab.ui.control.NumericEditField
        climLabel                       matlab.ui.control.Label
        AvgYCheck                       matlab.ui.control.CheckBox
        AvgXCheck                       matlab.ui.control.CheckBox
        ColormapDrop                    matlab.ui.control.DropDown
        ColorbarDropDownLabel           matlab.ui.control.Label
        UpdateplotBtn                   matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        YLim2                           matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        YLim1                           matlab.ui.control.NumericEditField
        YLimsLabel                      matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        XLim2                           matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        XLim1                           matlab.ui.control.NumericEditField
        XLimsLabel                      matlab.ui.control.Label
        PlottingoptionsLabel_2          matlab.ui.control.Label
        SaveplotBtn                     matlab.ui.control.Button
        FrameSpinner                    matlab.ui.control.Spinner
        Frametoshow150Label             matlab.ui.control.Label
        AvgCheck                        matlab.ui.control.CheckBox
        ResultsDisplay                  matlab.ui.control.UIAxes
        ImagePirt                       matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function filemat = LoadMatrix(app,inputfile)
            load(inputfile); workspc = who; 
            for i = 1:length(workspc)
                variable = workspc{i}; matr = eval(variable); s = size(matr);
                if (s(1)>1) && (s(2)>1); filemat = eval(variable); end
            end                              
        end

        function [Xedge,Yedge] = CropImages(app,Path,Tcase) % Only valid for .mat input files
            global Path
            disp('-- Calculating region of interest')
            
            if strcmp(Tcase,'Thot')
                Timage_hot = LoadMatrix(app,Path.THot);
                %-- Plot a representive image
                figure;
                imagesc(Timage_hot(:,:,1));
            elseif strcmp(Tcase,'Tcold')
                Timage_cold = LoadMatrix(app,Path.TCold);
                %-- Plot a representive image
                figure;
                imagesc(Timage_cold(:,:,1));
            end
            
            % Plot settings
            shading interp
            axis off
            title('Select the left-top and right-bottom corners of the RoI and press ENTER')
            colormap jet
            disp('Select the left-top and right-bottom corners of the region of interest and press ENTER')
            
            %-- Get a constant Intensity region
            
            [Xedge,Yedge]   =   getpts; % Get the left-top and right-bottom corners of the cte Intensity region
            Xlim    = round(Xedge(1)):round(Xedge(2)); % Range of pixels where there is cte Intensity
            Ylim    = round(Yedge(1)):round(Yedge(2)); % Range of pixels where there is cte Intensity
            
            %-- Check the constant Intensity region
            if strcmp(Tcase,'Thot')
                figure;
                imagesc(Timage_hot(Ylim,Xlim,1))
            elseif strcmp(Tcase,'Tcold')
                figure;
                imagesc(Timage_cold(Ylim,Xlim,1))
            else
                error('Tcase must be either Thot or Tcold');
            end
            % caxis([min(min(Gl.Mat(20:end-20)))-max(max(Gl.Mat))])
            shading interp
            axis off
            colormap jet
            title('Check the RoI and press ENTER')
            pause
            close all
            
            disp('----> DONE')
            
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Filter
            global Path

            addpath("..\src\")
            addpath("..\src\@PIRT\")
            addpath("..\utils\")
            addpath("Images\")
            addpath("InputData\")

            load('InputTables.mat'); set(app.HTMTable,'data',HFS); % Fill HTM Table inputs
            set(app.HTMResultsTable,'data',["Mean value","-","-","-"; "Standard deviation","-","-","-"; "Error [%]","-","-","-"]); % Fill HTM Table results
            set(app.POD1Table,'data',PODRaw);set(app.POD2Table,'data',PODRaw);set(app.POD3Table,'data',PODRaw);set(app.POD4Table,'data',PODRaw);
            set(app.mPOD1Table,'data',mPODRaw);set(app.mPOD2Table,'data',mPODRaw);set(app.mPOD3Table,'data',mPODRaw);set(app.mPOD4Table,'data',mPODRaw);
            set(app.sgolay1Table,'data',sgolayRaw);set(app.sgolay2Table,'data',sgolayRaw);set(app.sgolay3Table,'data',sgolayRaw);set(app.sgolay4Table,'data',sgolayRaw);
            set(app.wiener1Table,'data',wienerRaw);set(app.wiener2Table,'data',wienerRaw);set(app.wiener3Table,'data',wienerRaw);set(app.wiener4Table,'data',wienerRaw);
            
            %%%% HTM error table setup
            errorNames = {'Thot/Tcold'; 'Ambient temperature'; 'Supplied voltage'; 'Supplied current'; 'TFS emissivity'; 'TFS density'; 'TFS Cp'; ...
                'TFS thickness'; 'TFS area'; 'TFS thermal conductivity'; 'Characteristic length'; 'Air thermal conductivity'; 'Stream velocity (required for St)'; 'dTdt'};
            checkcols = {false;false;false;false;false;false;false;false;false;false;false;false;false;false;};
            values = {'0.1'; '0.1';'0.1';'0.1';'0.1';'0.1';'0.1';'0.1';'0.1';'0.1';'0.1';'0.1';'0.1';'0.1';};
            data = [errorNames values checkcols];
            columneditable = [false true true];
            columnformat = {'char', 'char','logical'};
            set(app.HTMErrorTable,'data',data,'ColumnFormat', columnformat,'ColumnEditable', columneditable);
            %%%%

            set(app.CustomQTable,'data',["Custom term 1",1,"-","-"]);

            PanelTab_SelectionChanged(app, struct('Source', app.Tab1_Image))
            FilterDrop_SelectionChanged(app, struct('Source', app.Filter1DropDown));
            app.mainsubGrid.RowHeight = {'1x',0};
            
            Path.THot = 0; Path.TCold = 0;
        end

        % Image clicked function: imgShowHide, imgShowHide2
        function imgShowHideImageClicked(app, event)
            
            switch event.Source
                case app.imgShowHide
                    if app.mainGrid.ColumnWidth{1} == 500
                        app.imgShowHide.ImageSource = 'LT_HideRight.png';
                        app.mainGrid.ColumnWidth{1} = 0;
                    else
                        app.imgShowHide.ImageSource = 'LT_HideLeft.png';
                        app.mainGrid.ColumnWidth{1} = 500;
                    end
                case app.imgShowHide2
                    if app.PlotMainGrid.ColumnWidth{3} == 200
                        app.imgShowHide2.ImageSource = 'LT_HideLeft.png';
                        app.PlotMainGrid.ColumnWidth{3} = 0;
                    else
                        app.imgShowHide2.ImageSource = 'LT_HideLeft2.png';
                        app.PlotMainGrid.ColumnWidth{3} = 200;
                    end
            end

        end

        % Image clicked function: Tab1_Image, Tab2_Image, Tab3_Image, 
        % ...and 2 other components
        function PanelTab_SelectionChanged(app, event)
            switch event.Source
                case app.Tab1_Image; app.lateralGrid.RowHeight([2,4,6,8,10]) = {'1x',0,0,0,0};
                case app.Tab2_Image; app.lateralGrid.RowHeight([2,4,6,8,10]) = {0,'1x',0,0,0};
                case app.Tab3_Image; app.lateralGrid.RowHeight([2,4,6,8,10]) = {0,0,'1x',0,0};
                case app.Tab4_Image; app.lateralGrid.RowHeight([2,4,6,8,10]) = {0,0,0,'1x',0};
                case app.Tab5_Image; app.lateralGrid.RowHeight([2,4,6,8,10]) = {0,0,0,0,'1x'};    
            end
        end

        % Button pushed function: LoadColdBtn, LoadHotBtn
        function InputFilesButton(app, event)
            global Path
            [file,path,~] = uigetfile('*.mat'); % Only for .mat files
            switch event.Source
                case app.LoadHotBtn; Path.THot = fullfile(path,file);  app.FileLoadedHot.Value = strcat("Working with: ",file); app.HotInfo.Value = file; app.HotInfo2.Value = file;                            
                case app.LoadColdBtn; Path.TCold = fullfile(path,file); app.FileLoadedCold.Value = strcat("Working with: ",file); app.ColdInfo2.Value = file;
            end
            set(app.CropCheck, 'Enable', 'on'); % Enable the option to crop once there are files loaded
            app.ResultsTextArea.Value = 'PIRT not ready to display or save results. Run first the program'; % Set text in results box displays
            app.HTMmessage.Value = 'Run HTM module to save or display results'; % Set text in HTM box displays
        end

        % Callback function: CropBtn, CropCheck
        function CroppingOptions(app, event)
            global Path

            switch event.Source
                case app.CropCheck % Check button to enable or not the cropping options.
                    if app.CropCheck.Value == 1 
                        app.CropInfo.Value = 'Yes'; app.CroppingHTMInfo.Value = 'Yes'; set(app.CropBtn, 'Enable', 'on'); set(app.CropX1, 'Enable', 'on'); set(app.CropX2, 'Enable', 'on'); set(app.CropY1, 'Enable', 'on'); set(app.CropY2, 'Enable', 'on'); 
                    else
                        app.CropInfo.Value = 'No'; app.CroppingHTMInfo.Value = 'No'; set(app.CropBtn, 'Enable', 'off'); set(app.CropX1, 'Enable', 'off'); set(app.CropX2, 'Enable', 'off'); set(app.CropY1, 'Enable', 'off'); set(app.CropY2, 'Enable', 'off'); 
                    end

                case app.CropBtn % If crop button pushed, call CropImage fcn defined upwards and pick the points of cropping the file. It will be applied for all the files launched to filter.
                    if (Path.THot == 0) [Xedge,Yedge] = CropImages(app,Path.TCold,'Tcold');
                    elseif (Path.TCold == 0); [Xedge,Yedge] = CropImages(app,Path.THot,'Thot');
                    else; [Xedge,Yedge] = CropImages(app,Path.THot,'Thot'); end
                    app.CropX1.Value = Xedge(1); app.CropX2.Value = Xedge(2); app.CropY1.Value = Yedge(1); app.CropY2.Value = Yedge(2);
            end
            
        end

        % Callback function: Filter1ConfBtn, Filter1DropDown, 
        % ...and 6 other components
        function FilterDrop_SelectionChanged(app, event)
            switch event.Source
                case {app.Filter1DropDown, app.Filter1ConfBtn}
                    switch app.Filter1DropDown.Value
                        case 'None'; app.FilterGrid.RowHeight = {30,30,50,'1x',0,0,0,30}; app.GridFilter1.ColumnWidth = {0,0,0,0};
                        case 'POD'; app.FilterGrid.RowHeight = {30,30,50,'1x',0,0,0,30}; app.GridFilter1.ColumnWidth = {'1x',0,0,0};
                        case 'mPOD'; app.FilterGrid.RowHeight = {30,30,50,'1x',0,0,0,30}; app.GridFilter1.ColumnWidth = {0,'1x',0,0};
                        case 'sgolay32'; app.FilterGrid.RowHeight = {30,30,50,'1x',0,0,0,30}; app.GridFilter1.ColumnWidth = {0,0,'1x',0};
                        case 'wiener3'; app.FilterGrid.RowHeight = {30,30,50,'1x',0,0,0,30}; app.GridFilter1.ColumnWidth = {0,0,0,'1x'};
                    end
                case {app.Filter2DropDown, app.Filter2ConfBtn}
                    switch app.Filter2DropDown.Value
                        case 'None'; app.FilterGrid.RowHeight = {30,30,50,0,'1x',0,0,30}; app.GridFilter2.ColumnWidth = {0,0,0,0};
                        case 'POD'; app.FilterGrid.RowHeight = {30,30,50,0,'1x',0,0,30}; app.GridFilter2.ColumnWidth = {'1x',0,0,0};
                        case 'mPOD'; app.FilterGrid.RowHeight = {30,30,50,0,'1x',0,0,30}; app.GridFilter2.ColumnWidth = {0,'1x',0,0};
                        case 'sgolay32'; app.FilterGrid.RowHeight = {30,30,50,0,'1x',0,0,30}; app.GridFilter2.ColumnWidth = {0,0,'1x',0};
                        case 'wiener3'; app.FilterGrid.RowHeight = {30,30,50,0,'1x',0,0,30}; app.GridFilter2.ColumnWidth = {0,0,0,'1x'};
                    end
                case {app.Filter3DropDown, app.Filter3ConfBtn}
                    switch app.Filter3DropDown.Value
                        case 'None'; app.FilterGrid.RowHeight = {30,30,50,0,0,'1x',0,30}; app.GridFilter3.ColumnWidth = {0,0,0,0};
                        case 'POD'; app.FilterGrid.RowHeight = {30,30,50,0,0,'1x',0,30}; app.GridFilter3.ColumnWidth = {'1x',0,0,0};
                        case 'mPOD'; app.FilterGrid.RowHeight = {30,30,50,0,0,'1x',0,30}; app.GridFilter3.ColumnWidth = {0,'1x',0,0};
                        case 'sgolay32'; app.FilterGrid.RowHeight = {30,30,50,0,0,'1x',0,30}; app.GridFilter3.ColumnWidth = {0,0,'1x',0};
                        case 'wiener3'; app.FilterGrid.RowHeight = {30,30,50,0,0,'1x',0,30}; app.GridFilter3.ColumnWidth = {0,0,0,'1x'};
                    end
                case {app.Filter4DropDown, app.Filter4ConfBtn}
                    switch app.Filter4DropDown.Value
                        case 'None'; app.FilterGrid.RowHeight = {30,30,50,0,0,0,'1x',30}; app.GridFilter4.ColumnWidth = {0,0,0,0};
                        case 'POD'; app.FilterGrid.RowHeight = {30,30,50,0,0,0,'1x',30}; app.GridFilter4.ColumnWidth = {'1x',0,0,0};
                        case 'mPOD'; app.FilterGrid.RowHeight = {30,30,50,0,0,0,'1x',30}; app.GridFilter4.ColumnWidth = {0,'1x',0,0};
                        case 'sgolay32'; app.FilterGrid.RowHeight = {30,30,50,0,0,0,'1x',30}; app.GridFilter4.ColumnWidth = {0,0,'1x',0};
                        case 'wiener3'; app.FilterGrid.RowHeight = {30,30,50,0,0,0,'1x',30}; app.GridFilter4.ColumnWidth = {0,0,0,'1x'};
                    end
            end

            app.FiltersInfo.Value = strcat(app.Filter1DropDown.Value,',',app.Filter2DropDown.Value,',',...
                app.Filter3DropDown.Value,',',app.Filter4DropDown.Value);
            
        end

        % Button pushed function: RunPIRTButton
        function RunPIRTButtonPushed(app, event)
            global Path
            global PIRTObj
            global Timage_hot

            clear Filter

            app.ResultsTextArea.Value = 'PIRT not ready to display or save results. Run first the program';

            filter(1) = string(app.Filter1DropDown.Value); 
            filter(2) = string(app.Filter2DropDown.Value); 
            filter(3) = string(app.Filter3DropDown.Value); 
            filter(4) = string(app.Filter4DropDown.Value);

            index = 1;

            for i = 1:4
                switch filter(i)
                    case 'POD'
                        if index == 1; selected_cells = get(app.POD1Table,'Data');
                        elseif index == 2; selected_cells = get(app.POD2Table,'Data');
                        elseif index == 3; selected_cells = get(app.POD3Table,'Data'); 
                        else; selected_cells = get(app.POD4Table,'Data');
                        end
                        data = selected_cells(:,2);

                        Filter(index).Type = 'POD';
                        Filter(index).Parameters.selection = 'Thot';
                        if data(1)~='-'; Filter(index).Parameters.Criterion = data(1); end
                        if data(2)~='-'; Filter(index).Parameters.Nmod = str2double(data(2)); end
                        if data(3)~='-'; Filter(index).Parameters.Threshold = str2double(data(3)); end
                        if data(4)~='-'; Filter(index).Parameters.beta = str2double(data(4)); end

                        index = index+1;

                    case 'mPOD'
                        if index == 1; selected_cells = get(app.mPOD1Table,'Data');
                        elseif index == 2; selected_cells = get(app.mPOD2Table,'Data');
                        elseif index == 3; selected_cells = get(app.mPOD3Table,'Data'); 
                        else; selected_cells = get(app.mPOD4Table,'Data');
                        end
                        data = selected_cells(:,2);

                        Filter(index).Type = 'mPOD';
                        Filter(index).Parameters.selection = 'Thot';
                        if (strcmp('PR',data(1)))
                            Filter(index).Parameters.Type = 'PeakRem';
                        elseif (strcmp('FD',data(1)))
                            Filter(index).Parameters.Type = 'FreqDec';
                        else
                            Filter(index).Parameters.Type = 'both';
                        end
                        
                        Filter(index).Parameters.f_acquisition = str2double(data(2));

                        if data(3)~='-'; Filter(index).Parameters.Threshold = data(3); end
                        if (strcmp('PR',data(1))) || (strcmp('both',data(1)))
                            filtervec           = strsplit(data(4),',');
                            for j = 1:length(filtervec)
                                f_vec(j) = str2double(cell2mat(filtervec(j)));
                            end
                            Filter(index).Parameters.fpeaks = f_vec;
                            Filter(index).Parameters.w = str2double(data(5));
                        end
                        if (strcmp('FD',data(1))) || (strcmp('both',data(1)))
                            Filter(index).Parameters.N_regions = str2double(data(6));
                            Filter(index).Parameters.f_min = str2double(data(7));
                            Filter(index).Parameters.f_max = str2double(data(8));
                        end
                        
                        index = index+1;

                    case 'sgolay32'
                        if index == 1; selected_cells = get(app.sgolay1Table,'Data');
                        elseif index == 2; selected_cells = get(app.sgolay2Table,'Data');
                        elseif index == 3; selected_cells = get(app.sgolay3Table,'Data'); 
                        else; selected_cells = get(app.sgolay4Table,'Data');
                        end
                        data = selected_cells(:,2);

                        Filter(index).Type = 'sgolay32';
                        Filter(index).Parameters.selection = 'Thot';
                        if data(1)~='-'; elements = strsplit(data(1), ','); Filter(index).Parameters.Kernel_size  = str2double(elements); end
                        if data(2)~='-'; elements = strsplit(data(2), ','); Filter(index).Parameters.h  = str2double(elements); end

                        index = index+1;

                    case 'wiener3'
                        if index == 1; selected_cells = get(app.wiener1Table,'Data');
                        elseif index == 2; selected_cells = get(app.wiener2Table,'Data');
                        elseif index == 3; selected_cells = get(app.wiener3Table,'Data'); 
                        else; selected_cells = get(app.wiener4Table,'Data');
                        end
                        data = selected_cells(:,2);

                        Filter(index).Type = 'wiener3';
                        Filter(index).Parameters.selection = 'Thot';
                        if data(1)~='-'; Filter(index).Parameters.noise = str2double(data(1)); end
                        if data(2)~='-'; elements = strsplit(data(2), ','); Filter(index).Parameters.kernel  = str2double(elements); end
                        
                        index = index+1;
                end

                end


            %if ~(strcmp('None',app.CropInfo.Value)); Timage_cold = LoadMatrix(app,Path.TCold); end %Load cold images

            clear variableNamesCold 

            if ~(strcmp('None',app.HotInfo.Value)); Timage_hot = LoadMatrix(app,Path.THot); end %Load hot images

            clear variableNamesHot

            % Run PIRT
            if ((strcmp('No',app.CropInfo.Value))) && (~(strcmp('None',app.HotInfo.Value))) && (~(strcmp('None,None,None,None',app.FiltersInfo.Value)))
                h = waitbar(0, 'Running PIRT...');
                PIRTObj = PIRT('Thot',Timage_hot,...%Introduce images
                    'Filter',Filter);%Filter data
                PIRTObj = PIRTObj.go();
                app.ResultsTextArea.Value = 'Filtering completed. PIRT is ready to save or display results';
                set(app.FilteredHotHTMCheck, 'Enable', 'on');                
            elseif (~(strcmp('No',app.CropInfo.Value))) && (~(strcmp('None',app.HotInfo.Value))) && (~(strcmp('None,None,None,None',app.FiltersInfo.Value)))
                h = waitbar(0, 'Running PIRT...');
                PIRTObj = PIRT('Thot',Timage_hot,...%Introduce images
                    'Filter',Filter,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);%Filter data
                PIRTObj = PIRTObj.go();
                app.ResultsTextArea.Value = 'Filtering completed. PIRT is ready to save or display results';
                set(app.FilteredHotHTMCheck, 'Enable', 'on'); 
            else
                set(app.FilteredHotHTMCheck, 'Enable', 'off'); 
                return
            end

            waitbar(1, h, 'PIRT run completed!');
            close(h);

        end

        % Button pushed function: RunHTMBtn
        function RunHTMBtnButtonPushed(app, event)
            global Path
            global PIRTObj
            global prueba
            global Timage_cold
            global Timage_hot
            
            app.HTMmessage.Value = 'Run HTM module to save or display results';
            
            %HTM terms
            selected_cells = get(app.HTMTable,'Data');
            data = selected_cells(:,2);

            if data(1)~='-'; HFS.Type = convertStringsToChars(data(1)); end
            if data(2)~='-'; HFS.rho = str2double(data(2)); end
            if data(3)~='-'; HFS.cp = str2double(data(3)); end
            if data(4)~='-'; HFS.epsilon = str2double(data(4)); end
            if data(5)~='-'; HFS.Area = str2double(data(5)); end
            if data(6)~='-'; HFS.k = str2double(data(6)); end
            if data(7)~='-'; HFS.s = str2double(data(7)); end 
            if data(8)~='-'; HFS.lambdax = str2double(data(8)); end
            if data(9)~='-'; HFS.lambday = str2double(data(9)); end
            if data(10)~='-'; Conditions.L = str2double(data(10)); end
            if data(11)~='-'; Conditions.V = str2double(data(11)); end
            if data(12)~='-'; Conditions.I = str2double(data(12)); end
            if data(13)~='-'; Tc = str2double(data(13)); end
            if data(14)~='-'; Th = str2double(data(14)); end
            Conditions.Tamb = [Tc,Th];
            if data(15)~='-'; Conditions.Uinf = str2double(data(15)); end

            %Error terms
            selected_cells = get(app.HTMErrorTable,'Data');
            data = string(selected_cells(:,2));
            percen = string(selected_cells(:,3));

            if data(1)~='-'; if percen(1) == 'true'; Error.errorT = str2double(data(1))/100; else Error.errorT = str2double(data(1)); end 
            end
            if data(2)~='-'; if percen(2) == 'true'; Error.errorTamb = str2double(data(2))/100; else Error.errorTamb = str2double(data(2)); end 
            end 
            if data(3)~='-'; if percen(3) == 'true'; Error.errorV = str2double(data(3))/100; else Error.errorV = str2double(data(3)); end 
            end 
            if data(4)~='-'; if percen(4) == 'true'; Error.errorI = str2double(data(4))/100; else Error.errorI = str2double(data(4)); end
            end
            if data(5)~='-'; if percen(5) == 'true'; Error.errorEpsilon = str2double(data(5))/100; else Error.errorEpsilon = str2double(data(5)); end
            end
            if data(6)~='-'; if percen(6) == 'true'; Error.errorrho = str2double(data(6))/100; else Error.errorrho = str2double(data(6)); end
            end
            if data(7)~='-'; if percen(7) == 'true'; Error.errorcp = str2double(data(7))/100; else Error.errorcp = str2double(data(7)); end
            end
            if data(8)~='-'; if percen(8) == 'true'; Error.errors = str2double(data(8))/100; else Error.errors = str2double(data(8)); end
            end
            if data(9)~='-'; if percen(9) == 'true'; Error.errorA = str2double(data(9))/100; else Error.errorA = str2double(data(9)); end
            end
            if data(10)~='-'; if percen(10) == 'true'; Error.errorkplate = str2double(data(10))/100; else Error.errorkplate = str2double(data(10)); end
            end
            if data(11)~='-'; if percen(11) == 'true'; Error.errorLchar = str2double(data(11))/100; else Error.errorLchar = str2double(data(11)); end
            end
            if data(12)~='-'; if percen(12) == 'true'; Error.errork = str2double(data(12))/100; else Error.errork = str2double(data(12)); end
            end
            if data(13)~='-'; if percen(13) == 'true'; Error.errorUinf = str2double(data(13))/100; else Error.errorUinf = str2double(data(13)); end
            end
            if data(14)~='-'; if percen(14) == 'true'; Error.errordTdt = str2double(data(14))/100; else Error.errordTdt = str2double(data(14)); end
            end
            
            %Q terms
            Qcell = {};

            if app.CustomQCheck.Value == 1
                selected_cells = get(app.CustomQTable,'Data'); QCols = size(selected_cells, 1); 
                for i = 1:QCols
                    if selected_cells(i,2) ~='-'; QData = str2double(selected_cells(i,2)); Qcell{end + 1} = QData;
                    elseif selected_cells(i,3) ~='-'; QData = LoadMatrix(app,selected_cells(i,3)); Qcell{end + 1} = QData;
                    elseif selected_cells(i,4) ~='-'; QData = LoadMatrix(app,selected_cells(i,4)); Qcell{end + 1} = QData;
                    end
                end
            end
            
            %Load images
            if ~(strcmp('None',app.ColdInfo2.Value)); Timage_cold = LoadMatrix(app,Path.TCold); end %Load cold images

            clear variableNamesCold 

            if ~(strcmp('None',app.HotInfo2.Value)); if app.FilteredHotHTMCheck.Value == 0; Timage_hot = LoadMatrix(app,Path.THot); elseif (app.FilteredHotHTMCheck.Value == 1) && isfield(PIRTObj.result, 'Thot_new'); Timage_hot = PIRTObj.result.Thot_new; end; end %Load hot images

            clear variableNamesHot

            % Check image size
            if (size(Timage_hot,1) ~= size(Timage_cold,1)) || (size(Timage_hot,2) ~= size(Timage_cold,2)); Timage_cold = Timage_cold(app.CropY1.Value:app.CropY2.Value,app.CropX1.Value:app.CropX2.Value,:); end


            h = waitbar(0, 'Running HTM module...');
            % Run PIRT HTM
            if ~(strcmp('None',app.HotInfo2.Value)) && ~(strcmp('None',app.ColdInfo2.Value))
                
                if (app.NuCheck.Value == 1) && (app.hCheck.Value == 0) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','HFS',HFS,'Conditions',Conditions,'TimeDer','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','HFS',HFS,'Conditions',Conditions,'TimeDer');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 1) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','HFS',HFS,'Conditions',Conditions,'TimeDer','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','HFS',HFS,'Conditions',Conditions,'TimeDer');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 1) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 1) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','HFS',HFS,'Conditions',Conditions,'TimeDer','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','HFS',HFS,'Conditions',Conditions,'TimeDer');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 1) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 0)    
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 0) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','St','HFS',HFS,'Conditions',Conditions,'TimeDer');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 0) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','St','HFS',HFS,'Conditions',Conditions,'TimeDer');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                 
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 0) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                  
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 1) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                 
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 1) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                 
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 1) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                 
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 1) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                  
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 0) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                        
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 0) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 0)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo');
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                  
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 0) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 1)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                 
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 1) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 1)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                 
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 1) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 1)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                   
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 1) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 1)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                   
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 1) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 1)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                    
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 0) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 1)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','St','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','St','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                  
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 0) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 0) && (app.CustomQCheck.Value == 1)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','St','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','St','HFS',HFS,'Conditions',Conditions,'TimeDer','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                  
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 0) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 1)
                     if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                  
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 1) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 1)
                     if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                 
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 1) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 1)
                     if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                    
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 1) && (app.StCheck.Value == 0) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 1)
                     if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                 
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 1) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 1)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','h','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                  
                elseif  (app.NuCheck.Value == 0) && (app.hCheck.Value == 0) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 1)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                 
                elseif  (app.NuCheck.Value == 1) && (app.hCheck.Value == 0) && (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 1) && (app.CustomQCheck.Value == 1)
                    if (app.CropCheck.Value == 1) && (app.FilteredHotHTMCheck.Value == 0)
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell,'Crop',[app.CropX1.Value ,app.CropX2.Value;app.CropY1.Value ,app.CropY2.Value]);
                    else
                        prueba = PIRT('Thot',Timage_hot,'Tcold',Timage_cold,'CalculateHeatTransfer','Nu','St','HFS',HFS,'Conditions',Conditions,'TimeDer','Error',Error,'CalculateHeatTransferError','montecarlo','CustomQ',Qcell);
                    end
                    prueba = prueba.go();
                    app.HTMmessage.Value = 'HTM module is ready to save or display results';                 
                end
            else
                waitbar(1, h, 'PIRT run completed!');
                close(h);
                return;
            end
            
            waitbar(1, h, 'PIRT run completed!');
            close(h);

        end

        % Button pushed function: SaveHTMBtn, SaveplotBtn, 
        % ...and 1 other component
        function Saveresults(app, event)
            global prueba
            global PIRTObj

            switch event.Source
                case app.SaveresultsButton; result = PIRTObj.result;
                    extension = {'*.mat'}; [file, path] = uiputfile(extension);
                    store = strcat(path,file); save(store,'result');
                case app.SaveplotBtn
                    extension = {'*.jpg';'*.png';'*.pdf'}; [file, path] = uiputfile(extension);
                    store = strcat(path,file); exportgraphics(app.ResultsDisplay,store,'ContentType','vector')
                case app.SaveHTMBtn; result = prueba.result;
                    extension = {'*.mat'}; [file, path] = uiputfile(extension);
                    store = strcat(path,file); save(store,'result');
            end

        end

        % Button pushed function: DisplayBtn, DisplayHTMBtn, TableHTMBtn
        function DisplayResults(app, event)
            global prueba
            global Timage_cold
            global Timage_hot
            global PIRTObj
            global Path
            
            %Load images
            if ~(strcmp('No file loaded',app.FileLoadedCold.Value)); Timage_cold = LoadMatrix(app,Path.TCold); end %Load cold images

            clear variableNamesCold 

            if ~(strcmp('No file loaded',app.FileLoadedHot.Value)); Timage_hot = LoadMatrix(app,Path.THot); end %Load hot images

            clear variableNamesHot

            switch event.Source
                case app.DisplayBtn
                    % 
                    delete(app.ResultsDisplay);
        
                    % Create ResultsDisplay
                    app.ResultsDisplay = uiaxes(app.PlotMainGrid);
                    title(app.ResultsDisplay, 'Results')
                    zlabel(app.ResultsDisplay, 'Z')
                    app.ResultsDisplay.XLimitMethod = 'tight';
                    app.ResultsDisplay.YLimitMethod = 'tight';
                    app.ResultsDisplay.ZLimitMethod = 'tight';
                    app.ResultsDisplay.LineWidth = 1.5;
                    app.ResultsDisplay.Box = 'on';
                    app.ResultsDisplay.Layout.Row = 1;
                    app.ResultsDisplay.Layout.Column = 1;    
                    
                    app.mainsubGrid.RowHeight = {0,'1x'}; app.PlotMainGrid.ColumnWidth = {'1x',15,200};
                    colorbar(app.ResultsDisplay);
                    selection = app.Display1Selection.Value;

                    
                    if ~(strcmp('PIRT not ready to display or save results. Run first the program',app.ResultsTextArea.Value))
                        if (strcmp('Unfiltered cold images (cropped)',selection)) && (app.CropCheck.Value == 1)
                            imagesc(app.ResultsDisplay,Timage_cold(app.CropY1.Value:app.CropY2.Value,app.CropX1.Value:app.CropX2.Value,1));
                            title(app.ResultsDisplay, 'Unfiltered cold images');
                            app.FrameSpinner.Limits = [1,size(Timage_cold,3)];
                            app.Frametoshow150Label.Text = strcat('Select the frame to show (1-',num2str(size(Timage_cold,3)),'):');
                        elseif (strcmp('Unfiltered hot images (cropped)',selection)) && (app.CropCheck.Value == 1)
                            imagesc(app.ResultsDisplay,Timage_hot(app.CropY1.Value:app.CropY2.Value,app.CropX1.Value:app.CropX2.Value,1));
                            title(app.ResultsDisplay, 'Unfiltered hot images');
                            app.FrameSpinner.Limits = [1,size(Timage_hot,3)];
                            app.Frametoshow150Label.Text = strcat('Select the frame to show (1-',num2str(size(Timage_hot,3)),'):');
                        elseif (strcmp('Original hot images',selection))
                            imagesc(app.ResultsDisplay,Timage_hot(:,:,1));
                            title(app.ResultsDisplay, 'Original hot images');
                            app.FrameSpinner.Limits = [1,size(Timage_hot,3)];
                            app.Frametoshow150Label.Text = strcat('Select the frame to show (1-',num2str(size(Timage_hot,3)),'):');
                        elseif (strcmp('Original cold images',selection))
                            imagesc(app.ResultsDisplay,Timage_cold(:,:,1));
                            title(app.ResultsDisplay, 'Original cold images');
                            app.FrameSpinner.Limits = [1,size(Timage_hot,3)];
                            app.Frametoshow150Label.Text = strcat('Select the frame to show (1-',num2str(size(Timage_hot,3)),'):');                            
                        elseif (strcmp('Filtered hot images',selection))
                            imagesc(app.ResultsDisplay,PIRTObj.result.Thot_new(:,:,1));
                            title(app.ResultsDisplay, 'Filtered hot images');
                            app.FrameSpinner.Limits = [1,size(PIRTObj.result.Thot_new,3)];
                            app.Frametoshow150Label.Text = strcat('Select the frame to show (1-',num2str(size(PIRTObj.result.Thot_new,3)),'):');
                        end
                    end

                case app.DisplayHTMBtn
                    % 
                    delete(app.ResultsDisplay);
        
                    % Create ResultsDisplay
                    app.ResultsDisplay = uiaxes(app.PlotMainGrid);
                    title(app.ResultsDisplay, 'Results')
                    zlabel(app.ResultsDisplay, 'Z')
                    app.ResultsDisplay.XLimitMethod = 'tight';
                    app.ResultsDisplay.YLimitMethod = 'tight';
                    app.ResultsDisplay.ZLimitMethod = 'tight';
                    app.ResultsDisplay.LineWidth = 1.5;
                    app.ResultsDisplay.Box = 'on';
                    app.ResultsDisplay.Layout.Row = 1;
                    app.ResultsDisplay.Layout.Column = 1;    

                    app.mainsubGrid.RowHeight = {0,'1x'}; app.PlotMainGrid.ColumnWidth = {'1x',15,200};
                    colorbar(app.ResultsDisplay);
                    selection = app.HTMSelectionDrop.Value;

                    if ~(strcmp('Run HTM module to save or display results',app.HTMmessage.Value))
                        if (strcmp('Nusselt number (Nu)',selection)) && isfield(prueba.result, 'Nu')
                            imagesc(app.ResultsDisplay,prueba.result.Nu(:,:,1));
                            title(app.ResultsDisplay, 'Nusselt number [Nu]');
                            app.FrameSpinner.Limits = [1,size(prueba.result.Nu,3)];
                            app.Frametoshow150Label.Text = strcat('Select the frame to show (1-',num2str(size(prueba.result.Nu,3)),'):');
                        elseif (strcmp('Heat transfer coefficient (h)',selection)) && isfield(prueba.result, 'h')
                            imagesc(app.ResultsDisplay,prueba.result.h(:,:,1));
                            title(app.ResultsDisplay, 'Heat transfer coefficient [h]');
                            app.FrameSpinner.Limits = [1,size(prueba.result.h,3)];
                            app.Frametoshow150Label.Text = strcat('Select the frame to show (1-',num2str(size(prueba.result.h,3)),'):');
                        elseif (strcmp('Stanton number (St)',selection)) && isfield(prueba.result, 'St')
                            imagesc(app.ResultsDisplay,prueba.result.St(:,:,1));
                            title(app.ResultsDisplay, 'Stanton number [St]');
                            app.FrameSpinner.Limits = [1,size(prueba.result.St,3)];
                            app.Frametoshow150Label.Text = strcat('Select the frame to show (1-',num2str(size(prueba.result.St,3)),'):');
                        end
                    end
                
                case app.TableHTMBtn
                    if app.NuCheck.Value == 1; NuData = {num2str(mean(reshape(mean(prueba.result.Nu,3),1,[]))); num2str(mean(std(mean(prueba.result.Nu,3))))}; else; NuData = {"-";"-"}; end
                    if app.hCheck.Value == 1; hData = {num2str(mean(reshape(mean(prueba.result.h,3),1,[]))); num2str(mean(std(mean(prueba.result.h,3))))}; else; hData = {"-";"-"}; end
                    if app.StCheck.Value == 1; StData = {num2str(mean(reshape(mean(prueba.result.St,3),1,[]))); num2str(mean(std(mean(prueba.result.St,3))))}; else; StData = {"-";"-"}; end

                    if (app.NuCheck.Value == 1) && (app.ErrorCheckBox.Value == 1); NuError = num2str(mean(reshape(prueba.result.errorNu_p,1,[]))); else; NuError = "-"; end
                    if (app.hCheck.Value == 1) && (app.ErrorCheckBox.Value == 1); hError = num2str(mean(reshape(prueba.result.errorh_p,1,[]))); else; hError = "-"; end
                    if (app.StCheck.Value == 1) && (app.ErrorCheckBox.Value == 1); StError = num2str(mean(reshape(prueba.result.errorSt_p,1,[]))); else; StError = "-"; end
                    
                    set(app.HTMResultsTable,'data',["Mean value",NuData{1},hData{1},StData{1}; "Standard deviation",NuData{2},hData{2},StData{2}; "Error [%]",NuError,hError,StError]); % Fill HTM Table results
            end
            % Update the lims and clim values
            pause(1);
            xl = xlim(app.ResultsDisplay);
            yl = ylim(app.ResultsDisplay);
            cl = clim(app.ResultsDisplay);
            app.XLim1.Value = xl(1);
            app.XLim2.Value = xl(2);
            app.YLim1.Value = yl(1);
            app.YLim2.Value = yl(2);
            app.CLim1.Value = cl(1);
            app.CLim2.Value = cl(2);

        end

        % Callback function: CustomQCheck, DeleteRowQBtn, NewRowQBtn
        function CustomQButtons(app, event)
            switch event.Source
                case app.NewRowQBtn
                    oldData = get(app.CustomQTable,'Data'); num_filas = size(oldData, 1); newData = [oldData; strcat("Custom term ",num2str(num_filas+1)),1,"-","-"]; set(app.CustomQTable,'Data',newData);
                case app.DeleteRowQBtn
                    oldData = get(app.CustomQTable,'Data'); num_filas = size(oldData, 1); if num_filas>1; newData =oldData(1:num_filas-1, :); set(app.CustomQTable,'Data',newData); end
                case app.CustomQCheck
                    if app.CustomQCheck.Value == 1; set(app.CustomQTable,'Enable','on'); set(app.NewRowQBtn,'Enable','on'); set(app.DeleteRowQBtn,'Enable','on'); else set(app.CustomQTable,'Enable','off'); set(app.NewRowQBtn,'Enable','off'); set(app.DeleteRowQBtn,'Enable','off'); end
            end
        end

        % Button pushed function: UpdateplotBtn
        function PlottingOptions(app, event)
            global prueba
            global PIRTObj
            global Path

            Title = convertStringsToChars(get(app.ResultsDisplay, 'Title').String);
            if contains(Title, 'Original cold') && exist('Timage_cold', 'var')
                Timage_cold = LoadMatrix(app,Path.TCold);
                if app.AvgCheck.Value == 1; imagesc(app.ResultsDisplay,mean(Timage_cold,3));end
                %if app.RMSCheck.Value == 1; figure(); imagesc(srqt(mean((Timage_cold-mean(Timage_cold,3)).^2,3))); title('RMS');end
                if app.AvgXCheck.Value == 1; M = mean(Timage_cold,3); X = mean(M,2); figure(); plot(X); title('Average X profile'); grid on; ylabel('Temperature [K]'); end
                if app.AvgYCheck.Value == 1; M = mean(Timage_cold,3); Y = mean(M,1); figure(); plot(Y); title('Average Y profile'); grid on; ylabel('Temperature [K]'); end
                if (app.AvgCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.RMSCheck.Value == 0);imagesc(app.ResultsDisplay,Timage_cold(:,:,app.FrameSpinner.Value)); end
            end
            if contains(Title, 'Original hot') && exist('Timage_hot', 'var')
                Timage_hot = LoadMatrix(app,Path.THot);
                if app.AvgCheck.Value == 1; imagesc(app.ResultsDisplay,mean(Timage_hot,3));end
                %if app.RMSCheck.Value == 1; figure(); imagesc(sqrt(mean((Timage_hot-mean(Timage_hot,3)).^2,3))); title('RMS');end
                if app.AvgXCheck.Value == 1; M = mean(Timage_hot,3); X = mean(M,2); figure(); plot(X); title('Average X profile'); grid on; ylabel('Temperature [K]'); end
                if app.AvgYCheck.Value == 1; M = mean(Timage_hot,3); Y = mean(M,1); figure(); plot(Y); title('Average Y profile'); grid on; ylabel('Temperature [K]'); end
                if (app.AvgCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.RMSCheck.Value == 0);imagesc(app.ResultsDisplay,Timage_hot(:,:,app.FrameSpinner.Value)); end
            end            
            if contains(Title, 'Unfiltered cold') && exist('Timage_cold', 'var')
                Timage_cold = Timage_cold(app.CropY1.Value:app.CropY2.Value,app.CropX1.Value:app.CropX2.Value,:);
                if app.AvgCheck.Value == 1; imagesc(app.ResultsDisplay,mean(Timage_cold,3));end
                %if app.RMSCheck.Value == 1; figure(); imagesc(root(mean((Timage_cold-mean(Timage_cold,3)).^2,3))); title('RMS');end
                if app.AvgXCheck.Value == 1; M = mean(Timage_cold,3); X = mean(M,2); figure(); plot(X); title('Average X profile'); grid on; ylabel('Temperature [K]'); end
                if app.AvgYCheck.Value == 1; M = mean(Timage_cold,3); Y = mean(M,1); figure(); plot(Y); title('Average Y profile'); grid on; ylabel('Temperature [K]'); end
                if (app.AvgCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.RMSCheck.Value == 0);imagesc(app.ResultsDisplay,Timage_cold(:,:,app.FrameSpinner.Value)); end
            end
            if contains(Title, 'Unfiltered hot') && exist('Timage_hot', 'var')
                Timage_hot = Timage_hot(app.CropY1.Value:app.CropY2.Value,app.CropX1.Value:app.CropX2.Value,:);
                if app.AvgCheck.Value == 1; imagesc(app.ResultsDisplay,mean(Timage_hot,3));end
                %if app.RMSCheck.Value == 1; figure(); imagesc(sqrt(mean((Timage_hot-mean(Timage_hot,3)).^2,3))); title('RMS');end
                if app.AvgXCheck.Value == 1; M = mean(Timage_hot,3); X = mean(M,2); figure(); plot(X); title('Average X profile'); grid on; ylabel('Temperature [K]'); end
                if app.AvgYCheck.Value == 1; M = mean(Timage_hot,3); Y = mean(M,1); figure(); plot(Y); title('Average Y profile'); grid on; ylabel('Temperature [K]'); end
                if (app.AvgCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.RMSCheck.Value == 0);imagesc(app.ResultsDisplay,Timage_hot(:,:,app.FrameSpinner.Value)); end
            end
            if contains(Title, 'Filtered hot') && isfield(PIRTObj.result, 'Thot_new')
                Timage_hot = LoadMatrix(app,Path.THot); % Check if neccesary to crop. Used in RMS. Check also de Z dim in SGLOAY case
                if (size(Timage_hot,1) ~= size(PIRTObj.result.Thot_new,1)) || (size(Timage_hot,2) ~= size(PIRTObj.result.Thot_new,2)); Timage_hot = Timage_hot(app.CropY1.Value:app.CropY2.Value,app.CropX1.Value:app.CropX2.Value,:); end
                if (size(Timage_hot,3) ~= size(PIRTObj.result.Thot_new,3)); Timage_hot = Timage_hot(:,:,1:end-(size(Timage_hot,3)-size(PIRTObj.result.Thot_new,3)));end

                if app.AvgCheck.Value == 1; imagesc(app.ResultsDisplay,mean(PIRTObj.result.Thot_new,3));end
                if app.RMSCheck.Value == 1; rms_fil = sqrt(mean((Timage_hot-PIRTObj.result.Thot_new).^2,3)); if isfield(PIRTObj.filter_params.sgolay32_params{1,1}, 'w'); kernel(1) = PIRTObj.filter_params.sgolay32_params{1,1}.w(1); kernel(2) = PIRTObj.filter_params.sgolay32_params{1,1}.w(2); rms_fil = rms_fil(kernel(1):end-kernel(1),kernel(2):end-kernel(2)); end; figure(); imagesc(rms_fil); title('RMS'); clim([quantile(rms_fil(:),0.05),quantile(rms_fil(:),0.95)]); cb = colorbar(); ylabel(cb, 'Temperature [K]','FontSize',12,'Rotation',270); end
                if app.AvgXCheck.Value == 1; M = mean(PIRTObj.result.Thot_new,3); X = mean(M,2); figure(); plot(X); title('Average X profile'); grid on; ylabel('Temperature [K]'); end
                if app.AvgYCheck.Value == 1; M = mean(PIRTObj.result.Thot_new,3); Y = mean(M,1); figure(); plot(Y); title('Average Y profile'); grid on; ylabel('Temperature [K]'); end
                if (app.AvgCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.RMSCheck.Value == 0);imagesc(app.ResultsDisplay,PIRTObj.result.Thot_new(:,:,app.FrameSpinner.Value)); end
            end
            if contains(Title, 'Nusselt') && isfield(prueba.result, 'Nu')
                if app.AvgCheck.Value == 1; imagesc(app.ResultsDisplay,mean(prueba.result.Nu,3));end
                if app.RMSCheck.Value == 1; figure(); imagesc(sqrt(mean((prueba.result.Nu-mean(prueba.result.Nu,3)).^2,3))); title('RMS');end
                if app.AvgXCheck.Value == 1; M = mean(prueba.result.Nu,3); X = mean(M,2); figure(); plot(X); title('Average X profile'); grid on; ylabel('Nusselt [-]'); end
                if app.AvgYCheck.Value == 1; M = mean(prueba.result.Nu,3); Y = mean(M,1); figure(); plot(Y); title('Average Y profile'); grid on; ylabel('Nusselt [-]'); end
                if (app.AvgCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.RMSCheck.Value == 0);imagesc(app.ResultsDisplay,prueba.result.Nu(:,:,app.FrameSpinner.Value)); end
            end
            if contains(Title, 'Heat transfer') && isfield(prueba.result, 'h')
                if app.AvgCheck.Value == 1; imagesc(app.ResultsDisplay,mean(prueba.result.h,3));end
                if app.RMSCheck.Value == 1; figure(); imagesc(sqrt(mean((prueba.result.h-mean(prueba.result.h,3)).^2,3))); title('RMS');end
                if app.AvgXCheck.Value == 1; M = mean(prueba.result.h,3); X = mean(M,2); figure(); plot(X); title('Average X profile'); grid on; ylabel('Heat transfer coefficient [W/m²K]'); end
                if app.AvgYCheck.Value == 1; M = mean(prueba.result.h,3); Y = mean(M,1); figure(); plot(Y); title('Average Y profile'); grid on; ylabel('Heat transfer coefficient [W/m²K]'); end
                if (app.AvgCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.RMSCheck.Value == 0);imagesc(app.ResultsDisplay,prueba.result.h(:,:,app.FrameSpinner.Value)); end
            end
            if contains(Title, 'Stanton') && isfield(prueba.result, 'St')
                if app.AvgCheck.Value == 1; imagesc(app.ResultsDisplay,mean(prueba.result.St,3));end
                if app.RMSCheck.Value == 1; figure(); imagesc(sqrt(mean((prueba.result.St-mean(prueba.result.St,3)).^2,3))); title('RMS');end
                if app.AvgXCheck.Value == 1; M = mean(prueba.result.St,3); X = mean(M,2); figure(); plot(X); title('Average X profile'); grid on; ylabel('Stanton number [-]'); end
                if app.AvgYCheck.Value == 1; M = mean(prueba.result.St,3); Y = mean(M,1); figure(); plot(Y); title('Average Y profile'); grid on; ylabel('Stanton number [-]'); end
                if (app.AvgCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.AvgXCheck.Value == 0) && (app.RMSCheck.Value == 0);imagesc(app.ResultsDisplay,prueba.result.St(:,:,app.FrameSpinner.Value)); end
            end
                
               
            xlim(app.ResultsDisplay, [app.XLim1.Value,app.XLim2.Value]);
            ylim(app.ResultsDisplay, [app.YLim1.Value,app.YLim2.Value]);
            clim(app.ResultsDisplay, [app.CLim1.Value,app.CLim2.Value])
            colormap(app.ResultsDisplay, app.ColormapDrop.Value);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PIRTUIFigure = uifigure('Visible', 'off');
            app.PIRTUIFigure.Name = 'PIRT';
            app.PIRTUIFigure.Icon = fullfile(pathToMLAPP, 'Images', 'PIRT_color.png');
            app.mainGrid = uigridlayout(app.PIRTUIFigure);
            app.mainPanel = uipanel(app.mainGrid);
            app.mainsubGrid = uigridlayout(app.mainPanel);
            app.ImagePirt = uiimage(app.mainsubGrid);
            app.ImagePirt.ImageSource = 'PIRT_color.png';
            app.PlotMainGrid = uigridlayout(app.mainsubGrid);
            app.ResultsDisplay = uiaxes(app.PlotMainGrid);
            title(app.ResultsDisplay, 'Results')
            zlabel(app.ResultsDisplay, 'Z')
            app.ResultsDisplay.XLimitMethod = 'tight';
            app.ResultsDisplay.YLimitMethod = 'tight';
            app.ResultsDisplay.ZLimitMethod = 'tight';
            app.GridLayout5 = uigridlayout(app.PlotMainGrid);
            app.AvgCheck = uicheckbox(app.GridLayout5);
            app.AvgCheck.Text = 'Display time series average';
            app.Frametoshow150Label = uilabel(app.GridLayout5);
            app.Frametoshow150Label.Text = 'Frame to show (1-50):';
            app.FrameSpinner = uispinner(app.GridLayout5);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.RoundFractionalValues = 'on';
            app.FrameSpinner.Value = 1;
            app.SaveplotBtn = uibutton(app.GridLayout5, 'push');
            app.SaveplotBtn.ButtonPushedFcn = createCallbackFcn(app, @Saveresults, true);
            app.SaveplotBtn.Text = 'Save plot';
            app.PlottingoptionsLabel_2 = uilabel(app.GridLayout5);
            app.PlottingoptionsLabel_2.Text = 'Plotting options';
            app.XLimsLabel = uilabel(app.GridLayout5);
            app.XLimsLabel.Text = 'XLims: [';
            app.XLim1 = uieditfield(app.GridLayout5, 'numeric');
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = ',';
            app.XLim2 = uieditfield(app.GridLayout5, 'numeric');
            app.XLim2.Value = 1;
            app.Label_2 = uilabel(app.GridLayout5);
            app.Label_2.Text = ']';
            app.YLimsLabel = uilabel(app.GridLayout5);
            app.YLimsLabel.Text = 'YLims: [';
            app.YLim1 = uieditfield(app.GridLayout5, 'numeric');
            app.Label_3 = uilabel(app.GridLayout5);
            app.Label_3.Text = ',';
            app.YLim2 = uieditfield(app.GridLayout5, 'numeric');
            app.YLim2.Value = 1;
            app.Label_4 = uilabel(app.GridLayout5);
            app.Label_4.Text = ']';
            app.UpdateplotBtn = uibutton(app.GridLayout5, 'push');
            app.UpdateplotBtn.ButtonPushedFcn = createCallbackFcn(app, @PlottingOptions, true);
            app.UpdateplotBtn.Text = 'Update plot';
            app.ColorbarDropDownLabel = uilabel(app.GridLayout5);
            app.ColorbarDropDownLabel.Text = 'Colorbar';
            app.ColormapDrop = uidropdown(app.GridLayout5);
            app.ColormapDrop.Items = {'Parula', 'Turbo', 'HSV', 'Hot', 'Cool', 'Spring', 'Summer', 'Autumn', 'Winter', 'Gray', 'Bone', 'Copper', 'Pink', 'Sky', 'Jet', 'Lines', 'Colorcube', 'Prism', 'Flag', 'White'};
            app.ColormapDrop.Value = 'Parula';
            app.AvgXCheck = uicheckbox(app.GridLayout5);
            app.AvgXCheck.Text = 'Display average X profile';
            app.AvgYCheck = uicheckbox(app.GridLayout5);
            app.AvgYCheck.Text = 'Display average Y profile';
            app.climLabel = uilabel(app.GridLayout5);
            app.climLabel.Text = 'clim: [';
            app.CLim1 = uieditfield(app.GridLayout5, 'numeric');
            app.CLim2 = uieditfield(app.GridLayout5, 'numeric');
            app.CLim2.Value = 1;
            app.Label_5 = uilabel(app.GridLayout5);
            app.Label_5.Text = ',';
            app.Label_6 = uilabel(app.GridLayout5);
            app.Label_6.Text = ']';
            app.RMSCheck = uicheckbox(app.GridLayout5);
            app.RMSCheck.Text = 'RMS';
            app.imgShowHide2 = uiimage(app.PlotMainGrid);
            app.imgShowHide2.ImageClickedFcn = createCallbackFcn(app, @imgShowHideImageClicked, true);
            app.imgShowHide2.ImageSource = fullfile(pathToMLAPP, 'Images', 'LT_HideLeft2.png');
            app.lateralPanel = uipanel(app.mainGrid);
            app.lateralGrid = uigridlayout(app.lateralPanel);
            app.Tab1_Grid = uigridlayout(app.lateralGrid);
            app.INPUTIMAGESLabel = uilabel(app.Tab1_Grid);
            app.INPUTIMAGESLabel.Text = 'INPUT IMAGES';
            app.Tab1_Image = uiimage(app.Tab1_Grid);
            app.Tab1_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab1_Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'OpenFile_LT.png');
            app.Tab1Panel = uipanel(app.lateralGrid);
            app.Tab1Grid = uigridlayout(app.Tab1Panel);
            app.LoadHotBtn = uibutton(app.Tab1Grid, 'push');
            app.LoadHotBtn.ButtonPushedFcn = createCallbackFcn(app, @InputFilesButton, true);
            app.LoadHotBtn.Text = 'Load hot images';
            app.LoadColdBtn = uibutton(app.Tab1Grid, 'push');
            app.LoadColdBtn.ButtonPushedFcn = createCallbackFcn(app, @InputFilesButton, true);
            app.LoadColdBtn.Text = 'Load cold images';
            app.FileLoadedCold = uieditfield(app.Tab1Grid, 'text');
            app.FileLoadedCold.Editable = 'off';
            app.FileLoadedCold.Value = 'No file loaded';
            app.FileLoadedHot = uieditfield(app.Tab1Grid, 'text');
            app.FileLoadedHot.Editable = 'off';
            app.FileLoadedHot.Value = 'No file loaded';
            app.InputLabel = uilabel(app.Tab1Grid);
            app.InputLabel.Text = 'The supported input file types are: .mat';
            app.CropBtn = uibutton(app.Tab1Grid, 'push');
            app.CropBtn.ButtonPushedFcn = createCallbackFcn(app, @CroppingOptions, true);
            app.CropBtn.Enable = 'off';
            app.CropBtn.Text = 'Crop images';
            app.CroppedcoordinatesLabel = uilabel(app.Tab1Grid);
            app.CroppedcoordinatesLabel.Text = 'Cropped coordinates';
            app.GridLayout10 = uigridlayout(app.Tab1Grid);
            app.CropX1 = uieditfield(app.GridLayout10, 'numeric');
            app.CropX1.Limits = [0 Inf];
            app.CropX1.RoundFractionalValues = 'on';
            app.CropX1.Enable = 'off';
            app.CropX2 = uieditfield(app.GridLayout10, 'numeric');
            app.CropX2.Limits = [0 Inf];
            app.CropX2.RoundFractionalValues = 'on';
            app.CropX2.Enable = 'off';
            app.CropY1 = uieditfield(app.GridLayout10, 'numeric');
            app.CropY1.Limits = [0 Inf];
            app.CropY1.RoundFractionalValues = 'on';
            app.CropY1.Enable = 'off';
            app.CropY2 = uieditfield(app.GridLayout10, 'numeric');
            app.CropY2.Limits = [0 Inf];
            app.CropY2.RoundFractionalValues = 'on';
            app.CropY2.Enable = 'off';
            app.GridLayout11 = uigridlayout(app.Tab1Grid);
            app.X1Label = uilabel(app.GridLayout11);
            app.X1Label.Text = 'X1';
            app.X2Label = uilabel(app.GridLayout11);
            app.X2Label.Text = 'X2';
            app.Y1Label = uilabel(app.GridLayout11);
            app.Y1Label.Text = 'Y1';
            app.Y2Label = uilabel(app.GridLayout11);
            app.Y2Label.Text = 'Y2';
            app.CropCheck = uicheckbox(app.Tab1Grid);
            app.CropCheck.ValueChangedFcn = createCallbackFcn(app, @CroppingOptions, true);
            app.CropCheck.Enable = 'off';
            app.CropCheck.Text = 'Enable cropping';
            app.Tab2Panel = uipanel(app.lateralGrid);
            app.FilterGrid = uigridlayout(app.Tab2Panel);
            app.Filter1Label = uilabel(app.FilterGrid);
            app.Filter1Label.Text = 'Filter 1:';
            app.Filter3Label = uilabel(app.FilterGrid);
            app.Filter3Label.Text = 'Filter 3:';
            app.Filter2Label = uilabel(app.FilterGrid);
            app.Filter2Label.Text = 'Filter 2:';
            app.Filter4Label = uilabel(app.FilterGrid);
            app.Filter4Label.Text = 'Filter 4:';
            app.Filter1DropDown = uidropdown(app.FilterGrid);
            app.Filter1DropDown.Items = {'None', 'POD', 'mPOD', 'sgolay32', 'wiener3'};
            app.Filter1DropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDrop_SelectionChanged, true);
            app.Filter1DropDown.Value = 'None';
            app.Filter2DropDown = uidropdown(app.FilterGrid);
            app.Filter2DropDown.Items = {'None', 'POD', 'mPOD', 'sgolay32', 'wiener3'};
            app.Filter2DropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDrop_SelectionChanged, true);
            app.Filter2DropDown.Value = 'None';
            app.Filter3DropDown = uidropdown(app.FilterGrid);
            app.Filter3DropDown.Items = {'None', 'POD', 'mPOD', 'sgolay32', 'wiener3'};
            app.Filter3DropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDrop_SelectionChanged, true);
            app.Filter3DropDown.Value = 'None';
            app.Filter4DropDown = uidropdown(app.FilterGrid);
            app.Filter4DropDown.Items = {'None', 'POD', 'mPOD', 'sgolay32', 'wiener3'};
            app.Filter4DropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDrop_SelectionChanged, true);
            app.Filter4DropDown.Value = 'None';
            app.GridFilter1 = uigridlayout(app.FilterGrid);
            app.POD1Table = uitable(app.GridFilter1);
            app.POD1Table.ColumnName = {'POD1'; 'Value'};
            app.POD1Table.RowName = {};
            app.POD1Table.ColumnEditable = [false true];
            app.mPOD1Table = uitable(app.GridFilter1);
            app.mPOD1Table.ColumnName = {'mPOD1'; 'Value'};
            app.mPOD1Table.RowName = {};
            app.mPOD1Table.ColumnEditable = [false true];
            app.Sgolay1Grid = uigridlayout(app.GridFilter1);
            app.sgolay1Table = uitable(app.Sgolay1Grid);
            app.sgolay1Table.ColumnName = {'sgolay1'; 'Value'};
            app.sgolay1Table.RowName = {};
            app.sgolay1Table.ColumnEditable = [false true];
            app.Wiener1Grid = uigridlayout(app.GridFilter1);
            app.wiener1Table = uitable(app.Wiener1Grid);
            app.wiener1Table.ColumnName = {'wiener1'; 'Value'};
            app.wiener1Table.RowName = {};
            app.wiener1Table.ColumnEditable = [false true];
            app.TextArea_POD1 = uitextarea(app.GridFilter1);
            app.TextArea_POD1.Editable = 'off';
            app.TextArea_POD1.Value = {'Default options: Elbow criterion with a 0.999 threshold.'; 'The user can take control by selecting either the number of modes to keep for reconstruction (Nmod) or by selecting a truncation criterion. Elbow and Spectrum can be controlled by the truncation threshold (default 0.999) and the HardThreshold is controlled by beta (default aspect ratio of the snapshot matrix).'};
            app.GridFilter2 = uigridlayout(app.FilterGrid);
            app.POD2Table = uitable(app.GridFilter2);
            app.POD2Table.ColumnName = {'POD2'; 'Value'};
            app.POD2Table.RowName = {};
            app.POD2Table.ColumnEditable = [false true];
            app.mPOD2Table = uitable(app.GridFilter2);
            app.mPOD2Table.ColumnName = {'mPOD2'; 'Value'};
            app.mPOD2Table.RowName = {};
            app.mPOD2Table.ColumnEditable = [false true];
            app.Sgolay2Grid = uigridlayout(app.GridFilter2);
            app.sgolay2Table = uitable(app.Sgolay2Grid);
            app.sgolay2Table.ColumnName = {'sgolay2'; 'Value'};
            app.sgolay2Table.RowName = {};
            app.sgolay2Table.ColumnEditable = [false true];
            app.Wiener2Grid = uigridlayout(app.GridFilter2);
            app.wiener2Table = uitable(app.Wiener2Grid);
            app.wiener2Table.ColumnName = {'wiener2'; 'Value'};
            app.wiener2Table.RowName = {};
            app.wiener2Table.ColumnEditable = [false true];
            app.TextArea_POD2 = uitextarea(app.GridFilter2);
            app.TextArea_POD2.Editable = 'off';
            app.TextArea_POD2.Value = {'Default options: Elbow criterion with a 0.999 threshold.'; 'The user can take control by selecting either the number of modes to keep for reconstruction (Nmod) or by selecting a truncation criterion. Elbow and Spectrum can be controlled by the truncation threshold (default 0.999) and the HardThreshold is controlled by beta (default aspect ratio of the snapshot matrix).'};
            app.GridFilter3 = uigridlayout(app.FilterGrid);
            app.POD3Table = uitable(app.GridFilter3);
            app.POD3Table.ColumnName = {'POD3'; 'Value'};
            app.POD3Table.RowName = {};
            app.POD3Table.ColumnEditable = [false true];
            app.mPOD3Table = uitable(app.GridFilter3);
            app.mPOD3Table.ColumnName = {'mPOD3'; 'Value'};
            app.mPOD3Table.RowName = {};
            app.mPOD3Table.ColumnEditable = [false true];
            app.Sgolay3Grid = uigridlayout(app.GridFilter3);
            app.sgolay3Table = uitable(app.Sgolay3Grid);
            app.sgolay3Table.ColumnName = {'sgolay3'; 'Value'};
            app.sgolay3Table.RowName = {};
            app.sgolay3Table.ColumnEditable = [false true];
            app.Wiener3Grid = uigridlayout(app.GridFilter3);
            app.wiener3Table = uitable(app.Wiener3Grid);
            app.wiener3Table.ColumnName = {'wiener3'; 'Value'};
            app.wiener3Table.RowName = {};
            app.wiener3Table.ColumnEditable = [false true];
            app.TextArea_POD3 = uitextarea(app.GridFilter3);
            app.TextArea_POD3.Editable = 'off';
            app.TextArea_POD3.Value = {'Default options: Elbow criterion with a 0.999 threshold.'; 'The user can take control by selecting either the number of modes to keep for reconstruction (Nmod) or by selecting a truncation criterion. Elbow and Spectrum can be controlled by the truncation threshold (default 0.999) and the HardThreshold is controlled by beta (default aspect ratio of the snapshot matrix).'};
            app.GridFilter4 = uigridlayout(app.FilterGrid);
            app.POD4Table = uitable(app.GridFilter4);
            app.POD4Table.ColumnName = {'POD4'; 'Value'};
            app.POD4Table.RowName = {};
            app.POD4Table.ColumnEditable = [false true];
            app.mPOD4Table = uitable(app.GridFilter4);
            app.mPOD4Table.ColumnName = {'mPOD4'; 'Value'};
            app.mPOD4Table.RowName = {};
            app.mPOD4Table.ColumnEditable = [false true];
            app.Sgolay4Grid = uigridlayout(app.GridFilter4);
            app.sgolay4Table = uitable(app.Sgolay4Grid);
            app.sgolay4Table.ColumnName = {'sgolay4'; 'Value'};
            app.sgolay4Table.RowName = {};
            app.sgolay4Table.ColumnEditable = [false true];
            app.Wiener4Grid = uigridlayout(app.GridFilter4);
            app.wiener4Table = uitable(app.Wiener4Grid);
            app.wiener4Table.ColumnName = {'wiener4'; 'Value'};
            app.wiener4Table.RowName = {};
            app.wiener4Table.ColumnEditable = [false true];
            app.TextArea_POD4 = uitextarea(app.GridFilter4);
            app.TextArea_POD4.Editable = 'off';
            app.TextArea_POD4.Value = {'Default options: Elbow criterion with a 0.999 threshold.'; 'The user can take control by selecting either the number of modes to keep for reconstruction (Nmod) or by selecting a truncation criterion. Elbow and Spectrum can be controlled by the truncation threshold (default 0.999) and the HardThreshold is controlled by beta (default aspect ratio of the snapshot matrix).'};
            app.FilterSelectionGrid = uigridlayout(app.FilterGrid);
            app.Filter1ConfBtn = uibutton(app.FilterSelectionGrid, 'push');
            app.Filter1ConfBtn.ButtonPushedFcn = createCallbackFcn(app, @FilterDrop_SelectionChanged, true);
            app.Filter1ConfBtn.Text = 'Filter 1 config';
            app.Filter2ConfBtn = uibutton(app.FilterSelectionGrid, 'push');
            app.Filter2ConfBtn.ButtonPushedFcn = createCallbackFcn(app, @FilterDrop_SelectionChanged, true);
            app.Filter2ConfBtn.Text = 'Filter 2 config';
            app.Filter3ConfBtn = uibutton(app.FilterSelectionGrid, 'push');
            app.Filter3ConfBtn.ButtonPushedFcn = createCallbackFcn(app, @FilterDrop_SelectionChanged, true);
            app.Filter3ConfBtn.Text = 'Filter 3 config';
            app.Filter4ConfBtn = uibutton(app.FilterSelectionGrid, 'push');
            app.Filter4ConfBtn.ButtonPushedFcn = createCallbackFcn(app, @FilterDrop_SelectionChanged, true);
            app.Filter4ConfBtn.Text = 'Filter 4 config';
            app.TextArea_5 = uitextarea(app.FilterGrid);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'For the parameters where is possible to give a matrix as input, type the ENTIRE path where the .mat file with the matrix is located. For scalar values, just type the value in the corresponding cell. To leave a cell in blank type ''-''.'};
            app.Tab3Panel = uipanel(app.lateralGrid);
            app.GridLayout3 = uigridlayout(app.Tab3Panel);
            app.HotimagesloadedLabel_3 = uilabel(app.GridLayout3);
            app.HotimagesloadedLabel_3.Text = 'Hot image(s) loaded:';
            app.FilescroppedLabel = uilabel(app.GridLayout3);
            app.FilescroppedLabel.Text = 'Files cropped:';
            app.FiltersselectedinorderLabel = uilabel(app.GridLayout3);
            app.FiltersselectedinorderLabel.Text = 'Filters selected (in order):';
            app.CropInfo = uieditfield(app.GridLayout3, 'text');
            app.CropInfo.Editable = 'off';
            app.CropInfo.Value = 'No';
            app.FiltersInfo = uieditfield(app.GridLayout3, 'text');
            app.FiltersInfo.Editable = 'off';
            app.FiltersInfo.Value = 'None,None,None,None';
            app.RunPIRTButton = uibutton(app.GridLayout3, 'push');
            app.RunPIRTButton.ButtonPushedFcn = createCallbackFcn(app, @RunPIRTButtonPushed, true);
            app.RunPIRTButton.Text = 'Run PIRT';
            app.SaveresultsButton = uibutton(app.GridLayout3, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @Saveresults, true);
            app.SaveresultsButton.Text = 'Save results';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Display1Selection = uidropdown(app.GridLayout4);
            app.Display1Selection.Items = {'Original cold images', 'Original hot images', 'Unfiltered cold images (cropped)', 'Unfiltered hot images (cropped)', 'Filtered hot images'};
            app.Display1Selection.Value = 'Original cold images';
            app.PlottingoptionsLabel = uilabel(app.GridLayout4);
            app.PlottingoptionsLabel.Text = 'Plotting options';
            app.DisplayBtn = uibutton(app.GridLayout4, 'push');
            app.DisplayBtn.ButtonPushedFcn = createCallbackFcn(app, @DisplayResults, true);
            app.DisplayBtn.Text = 'Display';
            app.SelecttheresultstodisplayLabel = uilabel(app.GridLayout4);
            app.SelecttheresultstodisplayLabel.Text = 'Select the results to display:';
            app.ResultsTextArea = uitextarea(app.GridLayout3);
            app.ResultsTextArea.Editable = 'off';
            app.ResultsTextArea.Value = {'PIRT not ready to display or save results. Run first the program'};
            app.HotInfo = uieditfield(app.GridLayout3, 'text');
            app.HotInfo.Editable = 'off';
            app.HotInfo.Value = 'None';
            app.Tab4Panel = uipanel(app.lateralGrid);
            app.Tab4Grid = uigridlayout(app.Tab4Panel);
            app.Tab4InputLabel = uilabel(app.Tab4Grid);
            app.Tab4InputLabel.Text = 'Heat foil sensor and free stream conditions inputs';
            app.TabHTM = uitabgroup(app.Tab4Grid);
            app.Tab1 = uitab(app.TabHTM);
            app.Tab1.Title = 'Inputs';
            app.GridTab1Htm = uigridlayout(app.Tab1);
            app.HTMTable = uitable(app.GridTab1Htm);
            app.HTMTable.ColumnName = {'Parameter'; 'Magnitude'};
            app.HTMTable.RowName = {};
            app.HTMTable.SelectionType = 'row';
            app.HTMTable.ColumnEditable = [false true];
            app.ErrorestimationTab = uitab(app.TabHTM);
            app.ErrorestimationTab.Title = 'Error estimation';
            app.GridLayout6 = uigridlayout(app.ErrorestimationTab);
            app.HTMErrorTable = uitable(app.GridLayout6);
            app.HTMErrorTable.ColumnName = {'Error parameter'; 'Uncertainty'; 'Percentage'};
            app.HTMErrorTable.RowName = {};
            app.HTMErrorTable.SelectionType = 'row';
            app.HTMErrorTable.ColumnEditable = [false true true];
            app.TextArea_3 = uitextarea(app.GridLayout6);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'The uncertainty can be defined either as a percentage or magnitude. If the first option is chosen, click on their respective checkbox of the percentage column.'};
            app.CustomqtermsTab = uitab(app.TabHTM);
            app.CustomqtermsTab.Title = 'Custom q'''' terms';
            app.GridLayout9 = uigridlayout(app.CustomqtermsTab);
            app.CustomQCheck = uicheckbox(app.GridLayout9);
            app.CustomQCheck.ValueChangedFcn = createCallbackFcn(app, @CustomQButtons, true);
            app.CustomQCheck.Text = '';
            app.EnableqtermsLabel = uilabel(app.GridLayout9);
            app.EnableqtermsLabel.Text = 'Enable q'''' terms';
            app.CustomQTable = uitable(app.GridLayout9);
            app.CustomQTable.ColumnName = {'Term name'; 'Scalar definition'; '2D definition'; '3D definition'};
            app.CustomQTable.RowName = {};
            app.CustomQTable.SelectionType = 'row';
            app.CustomQTable.ColumnEditable = true;
            app.CustomQTable.Enable = 'off';
            app.DeleteRowQBtn = uibutton(app.GridLayout9, 'push');
            app.DeleteRowQBtn.ButtonPushedFcn = createCallbackFcn(app, @CustomQButtons, true);
            app.DeleteRowQBtn.Enable = 'off';
            app.DeleteRowQBtn.Text = 'Delete last term';
            app.NewRowQBtn = uibutton(app.GridLayout9, 'push');
            app.NewRowQBtn.ButtonPushedFcn = createCallbackFcn(app, @CustomQButtons, true);
            app.NewRowQBtn.Enable = 'off';
            app.NewRowQBtn.Text = 'Add new term';
            app.TextArea_4 = uitextarea(app.GridLayout9);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'The q'''' terms can be defined with scalars, 2D or 3D values. Only one option is valid per term. For scalars, just introduce the value in the corresponding cell. For 2D and 3D terms, introduce the full path of the .mat file where the matrix or matrices are stored. Its important that the .mat file ONLY contains one variable, which corresponds to the 2D or 3D matrices. For the definitions not being used in a single term, just leave the cell with (-).'};
            app.OutputTab = uitab(app.TabHTM);
            app.OutputTab.Title = 'Output';
            app.GridLayout7 = uigridlayout(app.OutputTab);
            app.RunHTMBtn = uibutton(app.GridLayout7, 'push');
            app.RunHTMBtn.ButtonPushedFcn = createCallbackFcn(app, @RunHTMBtnButtonPushed, true);
            app.RunHTMBtn.Text = 'Compute terms';
            app.HTMmessage = uieditfield(app.GridLayout7, 'text');
            app.HTMmessage.Editable = 'off';
            app.HTMmessage.Value = 'Run HTM module to save or display results';
            app.IncludeerrorestimationLabel = uilabel(app.GridLayout7);
            app.IncludeerrorestimationLabel.Text = 'Include error estimation ';
            app.ErrorCheckBox = uicheckbox(app.GridLayout7);
            app.ErrorCheckBox.Text = '';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.NuLabel = uilabel(app.GridLayout8);
            app.NuLabel.Text = 'Nu';
            app.hLabel = uilabel(app.GridLayout8);
            app.hLabel.Text = 'h';
            app.StLabel = uilabel(app.GridLayout8);
            app.StLabel.Text = 'St';
            app.NuCheck = uicheckbox(app.GridLayout8);
            app.NuCheck.Text = '';
            app.hCheck = uicheckbox(app.GridLayout8);
            app.hCheck.Text = '';
            app.StCheck = uicheckbox(app.GridLayout8);
            app.StCheck.Text = '';
            app.SelectHTMoutputsLabel = uilabel(app.GridLayout7);
            app.SelectHTMoutputsLabel.Text = 'Select HTM outputs:';
            app.ColdimagesloadedLabel_2 = uilabel(app.GridLayout7);
            app.ColdimagesloadedLabel_2.Text = 'Cold image(s) loaded:';
            app.HotimagesloadedLabel_2 = uilabel(app.GridLayout7);
            app.HotimagesloadedLabel_2.Text = 'Hot image(s) loaded:';
            app.ColdInfo2 = uieditfield(app.GridLayout7, 'text');
            app.ColdInfo2.Editable = 'off';
            app.ColdInfo2.Value = 'None';
            app.HotInfo2 = uieditfield(app.GridLayout7, 'text');
            app.HotInfo2.Editable = 'off';
            app.HotInfo2.Value = 'None';
            app.PlottingoptionsLabel_3 = uilabel(app.GridLayout7);
            app.PlottingoptionsLabel_3.Text = 'Plotting options';
            app.DisplayHTMBtn = uibutton(app.GridLayout7, 'push');
            app.DisplayHTMBtn.ButtonPushedFcn = createCallbackFcn(app, @DisplayResults, true);
            app.DisplayHTMBtn.Text = 'Display';
            app.SelecttheresultstodisplayLabel_2 = uilabel(app.GridLayout7);
            app.SelecttheresultstodisplayLabel_2.Text = 'Select the results to display:';
            app.HTMSelectionDrop = uidropdown(app.GridLayout7);
            app.HTMSelectionDrop.Items = {'Nusselt number (Nu)', 'Heat transfer coefficient (h)', 'Stanton number (St)'};
            app.HTMSelectionDrop.Value = 'Nusselt number (Nu)';
            app.SaveHTMBtn = uibutton(app.GridLayout7, 'push');
            app.SaveHTMBtn.ButtonPushedFcn = createCallbackFcn(app, @Saveresults, true);
            app.SaveHTMBtn.Text = 'Save results';
            app.TableHTMBtn = uibutton(app.GridLayout7, 'push');
            app.TableHTMBtn.ButtonPushedFcn = createCallbackFcn(app, @DisplayResults, true);
            app.TableHTMBtn.Text = 'Fill table';
            app.FilescroppedLabel_2 = uilabel(app.GridLayout7);
            app.FilescroppedLabel_2.Text = 'Files cropped:';
            app.CroppingHTMInfo = uieditfield(app.GridLayout7, 'text');
            app.CroppingHTMInfo.Editable = 'off';
            app.CroppingHTMInfo.Value = 'No';
            app.UsehotfilteredimagesLabel = uilabel(app.GridLayout7);
            app.UsehotfilteredimagesLabel.Text = 'Use hot filtered image(s):';
            app.FilteredHotHTMCheck = uicheckbox(app.GridLayout7);
            app.FilteredHotHTMCheck.Enable = 'off';
            app.FilteredHotHTMCheck.Text = '';
            app.Tab5Panel = uipanel(app.lateralGrid);
            app.GridLayout = uigridlayout(app.Tab5Panel);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'PIRT'; 'Processing InfraRed Thermography'; ''; 'Universidad Carlos III de Madrid'};
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'Logo UC3M II.png');
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Logo UC3M I.png');
            app.Tab2_Grid = uigridlayout(app.lateralGrid);
            app.FILTERSELECTIONLabel_2 = uilabel(app.Tab2_Grid);
            app.FILTERSELECTIONLabel_2.Text = 'FILTER SELECTION';
            app.Tab2_Image = uiimage(app.Tab2_Grid);
            app.Tab2_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab2_Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'LT_Detection.png');
            app.Tab3_Grid = uigridlayout(app.lateralGrid);
            app.RESULTSLabel = uilabel(app.Tab3_Grid);
            app.RESULTSLabel.Text = 'RESULTS';
            app.Tab3_Image = uiimage(app.Tab3_Grid);
            app.Tab3_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab3_Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'LT_report.png');
            app.Tab4_Grid = uigridlayout(app.lateralGrid);
            app.HEATTRANSFERMODULELabel = uilabel(app.Tab4_Grid);
            app.HEATTRANSFERMODULELabel.Text = 'HEAT TRANSFER MODULE';
            app.Tab4_Image = uiimage(app.Tab4_Grid);
            app.Tab4_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab4_Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'playback_LT.png');
            app.Tab5_Grid = uigridlayout(app.lateralGrid);
            app.INFOLabel = uilabel(app.Tab5_Grid);
            app.INFOLabel.Text = 'INFO';
            app.Tab5_Image = uiimage(app.Tab5_Grid);
            app.Tab5_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab5_Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'info_LT.png');
            app.imgShowHide = uiimage(app.mainGrid);
            app.imgShowHide.ImageClickedFcn = createCallbackFcn(app, @imgShowHideImageClicked, true);
            app.imgShowHide.ImageSource = fullfile(pathToMLAPP, 'Images', 'LT_HideLeft.png');
            app.HTMResultsTable = uitable(app.mainGrid);
            app.HTMResultsTable.ColumnName = {'Result type'; 'Nu'; 'h'; 'St'};
            app.HTMResultsTable.RowName = {};
            app.HTMResultsTable.SelectionType = 'row';
            % Show the figure after all components are created
            app.PIRTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIRT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PIRTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PIRTUIFigure)
        end
    end
end